classdef Pitt_SPARC_Signal_Inspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PittSPARCSignalInspectorUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        ParallelProcessingMenu          matlab.ui.container.Menu
        EnableMenu                      matlab.ui.container.Menu
        DisableMenu                     matlab.ui.container.Menu
        ThemeMenu                       matlab.ui.container.Menu
        SPARCMenu                       matlab.ui.container.Menu
        PittMenu                        matlab.ui.container.Menu
        DefaultMenu                     matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        InfoMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ConfigureTab                    matlab.ui.container.Tab
        FileConfigurationPanel          matlab.ui.container.Panel
        ReadChannelsEditFieldLabel      matlab.ui.control.Label
        ReadChannelsEditField           matlab.ui.control.EditField
        FileTypeEditFieldLabel          matlab.ui.control.Label
        FileTypeEditField               matlab.ui.control.EditField
        FilePathEditFieldLabel          matlab.ui.control.Label
        FilePathEditField               matlab.ui.control.EditField
        ExcludeFilesEditFieldLabel      matlab.ui.control.Label
        ExcludeFilesEditField           matlab.ui.control.EditField
        NewPathButton                   matlab.ui.control.Button
        ArtifactThresholdEditFieldLabel  matlab.ui.control.Label
        ArtifactThresholdEditField      matlab.ui.control.NumericEditField
        ViewArtifactsCheckBox           matlab.ui.control.CheckBox
        FilterSettingsPanel             matlab.ui.container.Panel
        HighcutoffHzEditFieldLabel      matlab.ui.control.Label
        HighcutoffHzEditField           matlab.ui.control.NumericEditField
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        LowcutoffHzEditFieldLabel       matlab.ui.control.Label
        LowcutoffHzEditField            matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        SignalDetectionPanel            matlab.ui.container.Panel
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.NumericEditField
        DownsampleFactorEditFieldLabel  matlab.ui.control.Label
        DownsampleFactorEditField       matlab.ui.control.NumericEditField
        SignalViewingPanel              matlab.ui.container.Panel
        BinTimeEditFieldLabel           matlab.ui.control.Label
        BinTimeEditField                matlab.ui.control.NumericEditField
        SignalTimeStartEditFieldLabel   matlab.ui.control.Label
        SignalTimeStartEditField        matlab.ui.control.NumericEditField
        SignalTimeEndEditFieldLabel     matlab.ui.control.Label
        SignalTimeEndEditField          matlab.ui.control.NumericEditField
        TimeDropDownLabel               matlab.ui.control.Label
        TimeDropDown                    matlab.ui.control.DropDown
        AmplitudeDropDownLabel          matlab.ui.control.Label
        AmplitudeDropDown               matlab.ui.control.DropDown
        MinimumVoltageEditFieldLabel    matlab.ui.control.Label
        MinimumVoltageEditField         matlab.ui.control.NumericEditField
        MaximumVoltageEditFieldLabel    matlab.ui.control.Label
        MaximumVoltageEditField         matlab.ui.control.NumericEditField
        ControlPanel                    matlab.ui.container.Panel
        LoadNewDataButton               matlab.ui.control.Button
        ClearDataButton                 matlab.ui.control.Button
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EmptyPanel                      matlab.ui.container.Panel
        DisplayTab                      matlab.ui.container.Tab
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        raw_data_fig_panel              matlab.ui.container.Panel
        filt_data_fig_panel             matlab.ui.container.Panel
        spike_data_fig_panel            matlab.ui.container.Panel
        bin_data_fig_panel              matlab.ui.container.Panel
    end

    
    properties (Access = private)
        DialogApp                   % Dialog box app
        channel_data = struct; % struct to temporarily hold channel data
        folder_path = '';
        file_list = struct;
        file_type = 'ns5'
        fs = 30e3;
        downsample_factor = 1;
        thresh = -70;
        chan_list = [];
        entity = 'raw';
        N_data = [];
        t_start = 0;
        t_start_ref = 0;
        t_end = 300;
        t_end_ref = 300;
        bin_time = 1;
        bin_xlim = [];
        MyTimer
        x_time=1;
        LastXYLims;
        time_type_scale = 1;
        artifacts_visible=false;
        theme = 'sparc';
        p_pool = false;
        primary_fig = false; % WARNING: must be set to true to allow figures to update with new channel prior to using function "update_figures"
        
    end
    
    properties (Access = public)
        filter_type = ''; % Description
        freq_range = '';
        alt_fs = 30e3
        raw_fig_Axes matlab.graphics.axis.Axes
        filt_fig_Axes matlab.graphics.axis.Axes
        spike_fig_Axes matlab.graphics.axis.Axes
        bin_fig_Axes matlab.graphics.axis.Axes
    end
    
    methods (Access = private)
        
        % Code that executes when loading new data
        function err = load_new_data(app, chan)
            
            temp_full_data = [];
            temp_file_list = app.file_list;
            ent = app.entity;
            
            try
                % use load_data function with neuroshare support to read for specific channel
                for i=1:size(temp_file_list,2)
                    
                    % Construct full file path string
                    filePath = fullfile(temp_file_list(i).folder,temp_file_list(i).name);
                    
                    % Load trellis file using function read_continuousData()
                    fprintf("-----\nLoading %s\n", temp_file_list(i).name)
                    analogData = read_continuousData(filePath, char(ent), chan);
                    
                    temp_full_data = [temp_full_data, analogData];
                end
                err = false;
                % Put completed channel data into struct
                app.channel_data.(['Channel_',num2str(chan)]).full_data = single(temp_full_data);
                app.channel_data.(['Channel_',num2str(chan)]).fs = app.SamplingRateEditField.Value*1000;
            catch
                err = true;
            end
        end
        
        function clean_new_data(app, chan)
            fprintf('-------------------\n')
            %app.ViewArtifactsCheckBox.Enable = false;
            %app.ArtifactThresholdEditField.Enable = false;
            factor = app.DownsampleFactorEditField.Value;
            new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/factor;
            artifact_thresh = app.ArtifactThresholdEditField.Value;
            
            
            fprintf('Cleaning Channel %02d\n', chan)
            temp_full_data = app.channel_data.(['Channel_',num2str(chan)]).full_data;
            
            % downsample data for faster processing
            temp_downsample_data = downsample(temp_full_data, factor);
            
            % Check for signal artifacts or missing data packets
            [clean_data, artifact_data] = clean_signal_data(temp_downsample_data, artifact_thresh, new_fs, 1); % data, threshold, fs, 1ms buffer
            
            % Set signal mean to zero
            clean_data = clean_data - mean(clean_data);
            
            % Put completed channel data into struct
            app.channel_data.(['Channel_',num2str(chan)]).artifact_data = artifact_data{1};
            app.channel_data.(['Channel_',num2str(chan)]).raw_data = single(clean_data);
            
            % Make sure data doesn't need to be cleaned again
            app.channel_data.(['Channel_',num2str(chan)]).need_update.raw = false;
            
            % Get size of data
            app.N_data = size(clean_data,2);
            
            % Update times
            check_time_bounds(app, chan)
        end
        
        function filter_new_data(app, chan)
            % Filter all channel data
            fprintf('-------------------\n')
            temp_chan_list = app.chan_list;
            new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
            fprintf('Filtering Channel %02d\n', chan)
            filt_type = app.FilterTypeDropDown.Value;
            raw_data = double(app.channel_data.(['Channel_',num2str(chan)]).raw_data);
            
            switch filt_type
                case 'Highpass'
                    % Create 2nd order butterworth filter, high pass with output parameters
                    high_cutoff = app.HighcutoffHzEditField.Value;
                    [b,a] = butter(2,high_cutoff/(new_fs/2),'high'); %7/30/19 - used to be 300 but lowered to catch unknown CAPs just in case
                    filt_data = single(filtfilt(b,a,raw_data)); %shrink byte size, convert to float
                    app.channel_data.(['Channel_',num2str(chan)]).filt_data = single(filt_data);
                    app.freq_range = ['High: ', num2str(high_cutoff), 'Hz'];
                    
                case 'Lowpass'
                    % Create 2nd order butterworth filter, low pass at 150Hz, with output parameters
                    low_cutoff = app.HighcutoffHzEditField.Value;
                    [b,a] = butter(2,low_cutoff/(new_fs/2),'low'); %7/30/19 - used to be 300 but lowered to catch unknown CAPs just in case
                    filt_data = single(filtfilt(b,a,raw_data)); %shrink byte size, convert to float
                    app.channel_data.(['Channel_',num2str(chan)]).filt_data = single(filt_data);
                    app.freq_range = ['Low: ', num2str(low_cutoff), 'Hz'];
                    
                case 'Bandpass'
                    high_cutoff = app.HighcutoffHzEditField.Value;
                    low_cutoff = app.LowcutoffHzEditField.Value;
                    cut_range = [low_cutoff, high_cutoff];
                    filt_data = single(bandpass(raw_data,cut_range,new_fs));
                    app.channel_data.(['Channel_',num2str(chan)]).filt_data = single(filt_data);
                    app.freq_range = ['Low: ', num2str(low_cutoff), ' | High: ', num2str(high_cutoff)];
                    
            end
            
            % Make sure data doesn't need to be filtered again
            app.channel_data.(['Channel_',num2str(chan)]).need_update.filt = false;
            
            app.filter_type = filt_type;
            
        end


        function bin_new_data(app, chan)
            fprintf('---------------\n')
            temp_chan_list = app.chan_list;
            new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
            fprintf('Searching for spikes on Channel %02d\n', chan)
            % Find all the points in the signal where the signal crosses threshold
            % which amount to detecting a spike.
            bin_ct = [];
            spike_amp_t = [];
            spike_amp_data = [];
            temp_ct=0;
            app.bin_time = app.BinTimeEditField.Value;
            bin_size = app.bin_time*new_fs*app.time_type_scale;
            
            filt_data = app.channel_data.(['Channel_',num2str(chan)]).filt_data;
            
            threshold = app.ThresholdEditField.Value;
            
            if threshold > 0
                aboveThreshold = (filt_data > threshold);
            else
                aboveThreshold = (filt_data < threshold);
            end
            
            aboveThreshold = [false, aboveThreshold, false];  %pad with 0's at ends
            edges = diff(aboveThreshold);
            rising = find(edges==1);     %rising/falling edges
            falling = find(edges==-1);
            
            
            if ~isempty(falling)% Check if the threshold is set too high or low to detect anything
                if falling(end) > length(filt_data)
                    falling = falling(1:end-1);
                end
            end
            
            for i=1:length(falling)
                if threshold > 0
                    [val,pos] = max(filt_data(rising(i):falling(i)));
                else
                    [val,pos] = min(filt_data(rising(i):falling(i)));
                end
                temp_t = rising(i) + pos-1;
                spike_amp_t = [spike_amp_t, temp_t];
                spike_amp_data = [spike_amp_data, val];
            end
            
            
            t = 0;
            bin_ct = [];
            while t <= length(filt_data)+bin_size
                temp_ct = length(spike_amp_t((spike_amp_t < (t+bin_size)) & (spike_amp_t > t)));
                if isempty(temp_ct)
                    temp_ct = 0;
                end
                bin_ct = [bin_ct, temp_ct];
                t = t + bin_size;
            end
            
            % Double check we have the right amount of bins for the data
            while length(bin_ct) > floor(length(filt_data)/(new_fs*app.time_type_scale))
                bin_ct = bin_ct(1:end-1);
            end
            
            app.channel_data.(['Channel_',num2str(chan)]).spike_data = single(spike_amp_data);
            app.channel_data.(['Channel_',num2str(chan)]).spike_t = single(spike_amp_t);
            app.channel_data.(['Channel_',num2str(chan)]).bin_data = single(bin_ct);
            
            % Make sure data doesn't needed to be binned again
            app.channel_data.(['Channel_',num2str(chan)]).need_update.spike = false;
        end
        
        function update_figures(app, chan)
            
            %if isempty(cur_tabs)
            %    % WARNING: primary_fig must be set to true to allow figures to update to the new channel selected
            %    app.primary_fig = true;
            %end
            
            if app.primary_fig==true
                
                disp_chan = (['Channel_',num2str(chan)]);
                
                % Set initial y-limits to filtered signal amplitudes
                %app.raw_fig_Axes.YLim = [app.MinimumVoltageEditField.Value app.MaximumVoltageEditField.Value];
                app.raw_fig_Axes.YLim = [-400 400];
                
                % Get amplitude values of whole signal
                app.MinimumVoltageEditField.Value = double(min(app.channel_data.(disp_chan).filt_data));
                app.MaximumVoltageEditField.Value = double(max(app.channel_data.(disp_chan).filt_data));
                
                % Get corresponding time.
                new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
                temp_data = length(app.channel_data.(['Channel_',num2str(chan)]).raw_data);
                app.x_time = linspace(0,app.N_data/(new_fs*app.time_type_scale),temp_data);
                
                % Get time limits of whole signal
                app.t_start = 0;
                app.t_start = app.t_start_ref/app.time_type_scale;
                app.t_end_ref = app.N_data/(new_fs*app.time_type_scale);
                app.t_end = app.t_end_ref/app.time_type_scale;
                app.SignalTimeStartEditField.Value = app.t_start;
                app.SignalTimeEndEditField.Value = app.t_end;
                
                % Update all figures
                update_bin_data_figure(app, chan);
                update_raw_data_figure(app, chan);
                update_filt_data_figure(app, chan);
                update_spike_data_figure(app, chan);
                
                % Re-enable disabled settings after updating figures
                %app.ArtifactThresholdEditField.Enable = true;
                %app.ViewArtifactsCheckBox.Enable = true;
                
                % Link x-axis properties of windows
                %update_xlim(app);
                %update_ylim(app);
                
                drawnow
                app.primary_fig = false;
            end
        end
        
        function update_ylim(app)
            tab_chan = app.DropDown.Value;
            chan = char(regexp(tab_chan,'[0-9]','Match'))';
            disp_chan = (['Channel_',num2str(chan)]);
            y_min = floor(app.MinimumVoltageEditField.Value);
            y_max = ceil(app.MaximumVoltageEditField.Value);
            %y_min = min(app.channel_data.(disp_chan).filt_data);
            %y_max = max(app.channel_data.(disp_chan).filt_data);
            
            % Manual input sets the view for all but bin figures
            app.raw_data_fig.YLim = [y_min y_max];
            app.filt_fig_Axes.YLim = [y_min y_max];
            app.spike_fig_Axes.YLim = [y_min y_max];
        end
        
        function update_xlim(app)
            
            if ~isempty(app.N_data)
                tab_chan = app.DropDown.Value;
                chan = char(regexp(tab_chan,'[0-9]','Match'))';
                disp_chan = (['Channel_',num2str(chan)]);
                check_time_bounds(app, chan)
                
                % Since raw data figure isn't linked to the other 3 with the
                % y-axis, it should update every time the time window changes
                new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
                temp_t_start = app.t_start*new_fs;
                if temp_t_start==0
                    temp_t_start = 1;
                end
                temp_t_end = app.t_end*new_fs;
                if temp_t_end > app.N_data
                    temp_t_end = app.N_data;
                end
                raw_data = app.channel_data.(disp_chan).raw_data(temp_t_start:temp_t_end);
                app.raw_fig_Axes.YLim = [min(raw_data) max(raw_data)];
            end
            
            app.raw_fig_Axes.XLim = [app.t_start app.t_end];
            app.filt_fig_Axes.XLim = [app.t_start app.t_end];
            app.spike_fig_Axes.XLim = [app.t_start app.t_end];
            app.bin_fig_Axes.XLim = [app.t_start app.t_end];
        end
        
        function update_raw_data_figure(app, chan)
            disp_chan = (['Channel_',num2str(chan)]);
            raw_data = app.channel_data.(disp_chan).raw_data;
            new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
            plot(app.raw_fig_Axes, app.x_time, raw_data)
            axis(app.raw_fig_Axes, 'tight');
            drawnow
            artifact_data = app.channel_data.(disp_chan).artifact_data;
            
            if artifact_data.time > 0
                if app.artifacts_visible==true
                    sig_min = 0;
                    sig_max = 0;
                    for i=1:size(artifact_data.signals,1)
                        hold(app.raw_data_fig, 'on')
                        plot(app.raw_data_fig, artifact_data.signals_t{i,:}./new_fs, artifact_data.signals{i,:},'-k');
                        
                        temp_min = min(artifact_data.signals{i,:});
                        if temp_min < sig_min
                            sig_min = temp_min;
                        end
                        temp_max = max(artifact_data.signals{i,:});
                        if temp_max > sig_max
                            sig_max = temp_max;
                        end
                    end
                    
                    app.raw_data_fig.YLim = [sig_min sig_max];
                    hold(app.raw_data_fig, 'off')
                    
                else
                    %app.raw_data_fig.YLim = ([min(raw_data) max(raw_data)]);
                    %update_ylim(app);
                end
            end
            
            %[tb,raw_btns] = axtoolbar(app.raw_data_fig,{'zoomin','zoomout','pan','restoreview'});
        end


        function update_filt_data_figure(app, chan)
            disp_chan = (['Channel_',num2str(chan)]);
            filt_data = app.channel_data.(disp_chan).filt_data;
            plot(app.filt_fig_Axes, app.x_time, filt_data);
            axis(app.filt_fig_Axes, 'tight');
            hold(app.filt_fig_Axes, 'on')
            plot(app.filt_fig_Axes, app.x_time, linspace(app.thresh,app.thresh,length(app.x_time)),'r','LineWidth',1);
            hold(app.filt_fig_Axes, 'off')
            drawnow
            
        end
        
        
        function update_spike_data_figure(app, chan)
            disp_chan = (['Channel_',num2str(chan)]);
            new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
            dots = {'LineStyle','none','Marker','.','MarkerSize',10};
            spike_amp_t = app.channel_data.(disp_chan).spike_t/(new_fs*app.time_type_scale);
            spike_amp_data = app.channel_data.(disp_chan).spike_data;
            plot(app.spike_fig_Axes, spike_amp_t, spike_amp_data, dots{:})
            axis(app.spike_fig_Axes, 'tight');
            drawnow
            
        end
        
        function update_bin_data_figure(app, chan)
            disp_chan = (['Channel_',num2str(chan)]);
            bin_ct = app.channel_data.(disp_chan).bin_data;
            bar(app.bin_fig_Axes, bin_ct');
            axis(app.bin_fig_Axes, 'tight');
            drawnow
            t_type = app.TimeDropDown.Value;
            
        end
        
        function sparc_menu_theme(app)
            % SPARC theme!
            app.ConfigureTab.BackgroundColor = [0.4, 0.3, 0.7];
            app.FileConfigurationPanel.BackgroundColor = [0.9,0.9,0.9];
            app.FilterSettingsPanel.BackgroundColor = [0.9,0.9,0.9];
            app.SignalDetectionPanel.BackgroundColor = [0.9,0.9,0.9];
            app.SignalViewingPanel.BackgroundColor = [0.9,0.9,0.9];
            app.ControlPanel.BackgroundColor = [0.9,0.9,0.9];
            app.EmptyPanel.BackgroundColor = [0.9,0.9,0.9];
            app.LoadNewDataButton.BackgroundColor = [0.72,0.27,1];
            app.ClearDataButton.BackgroundColor = [0.65,0.65,0.65];
            app.DisplayTab.BackgroundColor = [0.4, 0.3, 0.7];
            app.DropDown.BackgroundColor = [0.9,0.9,0.9];
            app.raw_data_fig_panel.BackgroundColor = [0.9,0.9,0.9];
            app.filt_data_fig_panel.BackgroundColor = [0.9,0.9,0.9];
            app.spike_data_fig_panel.BackgroundColor = [0.9,0.9,0.9];
            app.bin_data_fig_panel.BackgroundColor = [0.9,0.9,0.9];
        end
        
        function pitt_menu_theme(app)
            % Pitt theme!
            app.ConfigureTab.BackgroundColor = [0.25, 0.45, 0.7];
            app.FileConfigurationPanel.BackgroundColor = [0.9,0.85,0.75];
            app.FilterSettingsPanel.BackgroundColor = [0.9,0.85,0.75];
            app.SignalDetectionPanel.BackgroundColor = [0.9,0.85,0.75];
            app.SignalViewingPanel.BackgroundColor = [0.9,0.85,0.75];
            app.ControlPanel.BackgroundColor = [0.9,0.85,0.75];
            app.EmptyPanel.BackgroundColor = [0.9,0.85,0.75];
            app.LoadNewDataButton.BackgroundColor = [1.0,1.0,0];
            app.ClearDataButton.BackgroundColor = [0.65,0.65,0.65];
            app.DisplayTab.BackgroundColor = [0.25, 0.45, 0.7];
            app.DropDown.BackgroundColor = [0.9,0.85,0.75];
            app.raw_data_fig_panel.BackgroundColor = [0.9,0.85,0.75];
            app.filt_data_fig_panel.BackgroundColor = [0.9,0.85,0.75];
            app.spike_data_fig_panel.BackgroundColor = [0.9,0.85,0.75];
            app.bin_data_fig_panel.BackgroundColor = [0.9,0.85,0.75];
        end
        
        function default_menu_theme(app)
            % Default matlab theme
            app.ConfigureTab.BackgroundColor = [0.94, 0.94, 0.94];
            app.FileConfigurationPanel.BackgroundColor = [0.9,0.9,0.9];
            app.FilterSettingsPanel.BackgroundColor = [0.9,0.9,0.9];
            app.SignalDetectionPanel.BackgroundColor = [0.9,0.9,0.9];
            app.SignalViewingPanel.BackgroundColor = [0.9,0.9,0.9];
            app.ControlPanel.BackgroundColor = [0.9,0.9,0.9];
            app.EmptyPanel.BackgroundColor = [0.9,0.9,0.9];
            app.LoadNewDataButton.BackgroundColor = [0.56,0.8,0.24];
            app.ClearDataButton.BackgroundColor = [0.65,0.65,0.65];
            app.DisplayTab.BackgroundColor = [0.94, 0.94, 0.94];
            app.DropDown.BackgroundColor = [0.9,0.9,0.9];
            app.raw_data_fig_panel.BackgroundColor = [0.94,0.94,0.94];
            app.filt_data_fig_panel.BackgroundColor = [0.94,0.94,0.94];
            app.spike_data_fig_panel.BackgroundColor = [0.94,0.94,0.94];
            app.bin_data_fig_panel.BackgroundColor = [0.94,0.94,0.94];
        end
        
        function check_time_bounds(app, chan)
            new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
            % Update times
            t_0 = app.SignalTimeStartEditField.Value;
            t_1 = app.SignalTimeEndEditField.Value;
            if t_0<0 || isnan(t_0)
                app.SignalTimeStartEditField.Value = 0;
            end
            if t_1 > app.N_data/(new_fs*app.time_type_scale) || isnan(t_1)
                app.SignalTimeEndEditField.Value = app.N_data/(new_fs*app.time_type_scale);
            end
        end
        
        function update_status(app, input)
            app.EditField.Value = input;
            drawnow;
        end
        
        function update_notify(app,varargin)
            % This function lets other channels know that a change has been
            % made with the current channel figures that will affect the
            % rest. This is important to have so graphs will update accordingly
            update_raw = false;
            update_filt = false;
            update_spike = false;
            
            if ~isempty(varargin)
                if ismember('raw', varargin)
                    update_raw = true;
                end
                if ismember('filt', varargin)
                    update_filt = true;
                end
                if ismember('spike', varargin)
                    update_spike = true;
                end
            end
            cur_tabs = app.DropDown.Items;
            for i=1:length(cur_tabs)
                tab_chan = cur_tabs{i};
                chan = char(regexp(tab_chan,'[0-9]','Match'))';
                if update_raw==true
                    app.channel_data.(['Channel_',num2str(chan)]).need_update.raw = true;
                end
                if update_filt==true
                    app.channel_data.(['Channel_',num2str(chan)]).need_update.filt = true;
                end
                if update_spike==true
                    app.channel_data.(['Channel_',num2str(chan)]).need_update.spike = true;
                end
            end
        end
        
        
        function evaluate_file_type(app)
            unit_type = app.FileTypeEditField.Value;
            if strcmp(unit_type, 'nf3')
                app.entity = 'hi-res';
                app.fs = 2e3;
                app.downsample_factor = 1;
                app.SamplingRateEditField.Value = 2;
                app.alt_fs = app.fs;
            elseif strcmp(unit_type, 'ns5')
                app.entity = 'raw';
                app.fs = 30e3;
                app.downsample_factor = 1;
                app.SamplingRateEditField.Value = 30;
                app.alt_fs = app.fs;
            elseif strcmp(unit_type, 'ns2')
                app.entity = 'analog';
                app.fs = 30e3;
                app.downsample_factor = 1;
                app.SamplingRateEditField.Value = 30;
                app.alt_fs = app.fs;
            else
                beep
                fprintf('Unrecognized file type: %s', unit_type)
            end
        end
        
        function choose_theme(app)
            theme_select = app.theme;
            switch theme_select
                case 'sparc'
                    sparc_menu_theme(app);
                case 'default'
                    default_menu_theme(app);
                case 'pitt'
                    pitt_menu_theme(app);
            end
        end
        
        function update_channel_list(varargin)
            app = varargin{1};
            if length(varargin)>1
               chan = varargin{2}; 
            else
                
            end
            new_tabs = {};
            cur_tabs = app.DropDown.Items;
            temp_chan = ['Channel ',num2str(chan)];
            new_tabs = [new_tabs, temp_chan];
            updated_chan_tabs = sort([cur_tabs, new_tabs]);
            
            app.DropDown.Items = updated_chan_tabs;
        end
        
        function load_without_pool(app, cur_tabs, new_chan_length, temp_chan_list)
            for i=1:new_chan_length
                chan = temp_chan_list(i);
                temp_chan = ['Channel ',num2str(chan)];
                
                % check if the channel already exists
                if any(strcmp(cur_tabs,temp_chan))
                    beep;
                    fprintf('Channel %0.2d already exists\n', chan);
                    %return
                else
                    % Read new data
                    update_status(app, 'Reading Data');
                    err = load_new_data(app, chan);
                    if err==true
                        beep;
                        fprintf('Error processing Channel %d. Either incorrect file type or channel data does not exist\n', chan)
                    else
                        % Cleaning Data
                        update_status(app, 'Cleaning Data');
                        clean_new_data(app, chan);
                        
                        % Filtering Data
                        update_status(app, 'Filtering Data');
                        filter_new_data(app, chan);
                        
                        % Bin new data
                        update_status(app, 'Binning Data');
                        bin_new_data(app, chan);
                        
                        % Append new channels to Display, sorting all channel numbers
                        update_channel_list(app, chan)
                        
                        % Update figures
                        update_status(app, 'Updating Figures');
                        update_figures(app, chan);
            
                    end
                end
            end
            
        end
        
        function load_with_pool(app, cur_tabs, new_chan_length, temp_chan_list)
            
            parfor i=1:new_chan_length
                chan = temp_chan_list(i);
                temp_chan = ['Channel ',num2str(chan)];
                
                % check if the channel already exists
                if any(strcmp(cur_tabs,temp_chan))
                    beep;
                    fprintf('Channel %0.2d already exists\n', chan);
                    %return
                else
                    % Read new data
                    update_status(app, 'Reading Data');
                    err = load_new_data(app, chan);
                    if err==true
                        beep;
                        fprintf('Error processing Channel %d. Either incorrect file type or channel data does not exist\n', chan)
                    else
                        % Cleaning Data
                        update_status(app, 'Cleaning Data');
                        clean_new_data(app, chan);
                        
                        % Filtering Data
                        update_status(app, 'Filtering Data');
                        filter_new_data(app, chan);
                        
                        % Bin new data
                        update_status(app, 'Binning Data');
                        bin_new_data(app, chan);
                    end
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Close previous figure instances
            close all;
            
            % Figure Initialize with configured settings
            choose_theme(app);
            movegui(app.PittSPARCSignalInspectorUIFigure,'center');
            %set(app.PittSPARCSignalInspectorUIFigure, 'WindowKeyPressFcn', @KeyPress);
            app.SaveMenu.Enable = true;
            
            % Get file path
            app.folder_path = app.FilePathEditField.Value;
            
            app.EditField.Value = 'Ready';
            app.MyTimer = timer('BusyMode','drop','Name','fred','ExecutionMode','fixedRate','Period',0.1,...
                'TimerFcn',@(T,E) MyTimerCallback(app,T,E));
            
            % get file type
            evaluate_file_type(app)
            app.file_type = app.FileTypeEditField.Value;
            if strcmp(app.file_type,'')
                app.file_type = 'ns5';
            end
            
            % Get threshold
            app.thresh = app.ThresholdEditField.Value;
            if app.thresh == 0 || isnan(app.thresh)
                app.thresh = -70;
            end
            
            % Get bin time
            t_type = app.TimeDropDownLabel.Text;
            if strcmp(t_type,'Hour')==true
                app.time_type_scale = 3600;
            elseif strcmp(t_type,'Minutes')==true
                app.time_type_scale = 60;
            elseif strcmp(t_type,'Seconds')==true
                app.bin_times_cale = 1;
            end
            
            % Get downsampling factor and new fs
            app.downsample_factor = app.DownsampleFactorEditField.Value;
            app.alt_fs = app.fs/app.downsample_factor;
            
            % Get start and end times
            app.t_start = app.SignalTimeStartEditField.Value;
            app.t_end = app.SignalTimeEndEditField.Value;
            
            % Link axis
            app.raw_fig_Axes = axes(app.raw_data_fig_panel);
            app.filt_fig_Axes = axes(app.filt_data_fig_panel);
            app.spike_fig_Axes = axes(app.spike_data_fig_panel);
            app.bin_fig_Axes = axes(app.bin_data_fig_panel);
            linkaxes([app.raw_fig_Axes, app.filt_fig_Axes, app.spike_fig_Axes, app.bin_fig_Axes],'x');
            %linkaxes([app.raw_fig_Axes, app.filt_fig_Axes, app.spike_fig_Axes],'y');
            
            
            % Update x-axis limits with figures
            %update_xlim(app)
            
        end

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
            new_path = app.folder_path;
            if isempty(new_path)
                beep;
                fprintf('Warning, no directory selected\n')
                return
            else
                cd(new_path);
            end
            % Get all data file names to ignore
            excluded_files = strtrim(split(app.ExcludeFilesEditField.Value,','));
            if ~isempty(excluded_files{1})
                for i=1:length(excluded_files)
                    if length(excluded_files{i})<12
                        beep;
                        fprintf('Error: Excluded file name incorrect length\n')
                        return
                    elseif length(excluded_files{i})>12
                        excluded_files{i} = excluded_files{i}(1:12);
                    end
                end
            end
            
            % Read contents of directory and sort file names
            % First get all files located in directory
            temp_list = dir(['*.',app.file_type]);
            ct=1;
            for i=1:length(temp_list)
                % Check if file matches any case to ignore, otherwise keep
                if ~any(ismember(excluded_files,temp_list(i).name(1:end-4)))
                    file_list_temp(ct) = temp_list(i);
                    ct=ct+1;
                end
            end
            [~, reindex] = sort( str2double( regexp( {file_list_temp.name}, '\d+', 'match', 'once' )));
            app.file_list    = file_list_temp(reindex);
            
            % Get channels to read
            input_chans = strsplit(app.ReadChannelsEditField.Value,',');
            temp_chan_list = [];
            for item=1:length(input_chans)
                temp_chan_list = [temp_chan_list, str2num(input_chans{item})];
                % This option to store channel history can be for later use
                %app.chan_list = [app.chan_list, str2num(chans{item})];
            end
            
            % Check if channel data exists, and if not, proceed with pipeline
            cur_tabs = app.DropDown.Items;
            if isempty(cur_tabs)
                % WARNING: primary_fig must be set to true to allow figures to update to the new channel selected
                app.primary_fig = true;
            end
            
            new_chan_length = length(temp_chan_list);
            app.SaveMenu.Enable = false;
            
            if app.p_pool==true
                load_with_pool(app, cur_tabs, new_chan_length, temp_chan_list);
            else
                load_without_pool(app, cur_tabs, new_chan_length, temp_chan_list);
            end
            
            % Append new channels to Display, sorting all channel numbers
            if app.p_pool==true
                tab_chan = cur_tabs{1};
                chan = char(regexp(tab_chan,'[0-9]','Match'))';
                update_channel_list(app, chan)
                
                % Update figures
                update_status(app, 'Updating Figures');
                update_figures(app, chan);
            end


            app.SaveMenu.Enable = true;
            %app.ViewArtifactsCheckBox.Enable = true;
            %app.ArtifactThresholdEditField.Enable = true;
            
            update_status(app, 'Ready');
            %stop(app.MyTimer);
            %start(app.MyTimer);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            % Update figures with new channel
            tab_chan = app.DropDown.Value;
            chan = char(regexp(tab_chan,'[0-9]','Match'))';
            %chan = str2double(tab_chan(end));
            disp_chan = ['Channel_',num2str(chan)];
            
            % Update size of data vector
            app.N_data = length(app.channel_data.(['Channel_',num2str(chan)]).raw_data);
            
            % check if channel data needs to be redone
            if app.channel_data.(['Channel_',num2str(chan)]).need_update.raw == true
                % Cleaning Data
                update_status(app, 'Cleaning Data');
                clean_new_data(app, chan);
            end
            if app.channel_data.(['Channel_',num2str(chan)]).need_update.filt == true
                % Filtering Data
                update_status(app, 'Filtering Data');
                filter_new_data(app, chan);
            end
            if app.channel_data.(['Channel_',num2str(chan)]).need_update.spike == true
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
            end
            
            % Get amplitude values of signal
            app.MinimumVoltageEditField.Value = double(min(app.channel_data.(disp_chan).filt_data));
            app.MaximumVoltageEditField.Value = double(max(app.channel_data.(disp_chan).filt_data));
            update_status(app, 'Updating Figures');
            app.primary_fig = true;
            update_figures(app, chan);
            
            % Set initial y-limits to filtered signal amplitudes
            %app.raw_fig_Axes.YLim = [app.MinimumVoltageEditField.Value app.MaximumVoltageEditField.Value];
            %app.raw_fig_Axes.YLim = [-400 400];
            update_status(app, 'Ready');
        end

        % Value changed function: SignalTimeStartEditField
        function SignalTimeStartEditFieldValueChanged(app, event)
            app.t_start = app.SignalTimeStartEditField.Value;
            
            %check if data exists
            if ~isempty(app.N_data)
                % Update figures
                update_status(app, 'Updating Figures');
                tab_chan = app.DropDown.Value;
                chan = char(regexp(tab_chan,'[0-9]','Match'))';
                disp_chan = ['Channel_',num2str(chan)];
                
                check_time_bounds(app, chan)
                
                update_xlim(app);
                
                update_status(app, 'Ready');
            end
        end

        % Value changed function: SignalTimeEndEditField
        function SignalTimeEndEditFieldValueChanged(app, event)
            % Check that data exists before changing the window, otherwise store
            app.t_end = app.SignalTimeEndEditField.Value;
            
            if ~isempty(app.N_data)
                % Update figures
                update_status(app, 'Updating Figures');
                tab_chan = app.DropDown.Value;
                chan = char(regexp(tab_chan,'[0-9]','Match'))';
                disp_chan = ['Channel_',num2str(chan)];
                
                check_time_bounds(app, chan)
                
                update_xlim(app);
                
                update_status(app, 'Ready');
            end
        end

        % Value changed function: HighcutoffHzEditField
        function HighcutoffHzEditFieldValueChanged(app, event)
            value = app.HighcutoffHzEditField.Value;
            
            if ~isempty(app.N_data)
                
                update_notify(app,'filt','spike'); % notify that all channels will need to be redone through the pipeline when selected
                disp_chan = app.DropDown.Value;
                chan = char(regexp(disp_chan,'[0-9]','Match'))';
                
                % Filtering Data
                update_status(app, 'Filtering Data');
                filter_new_data(app, chan);
                
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
                
                % Update figures
                update_status(app, 'Updating Figures');
                app.primary_fig = true;
                update_figures(app, chan);
                
                update_status(app, 'Ready');
            end
            
        end

        % Value changed function: LowcutoffHzEditField
        function LowcutoffHzEditFieldValueChanged(app, event)
            value = app.LowcutoffHzEditField.Value;
            if ~isempty(app.N_data)
                
                update_notify(app,'filt','spike'); % notify that all channels will need to be redone through the pipeline when selected
                disp_chan = app.DropDown.Value;
                chan = char(regexp(tab_chan,'[0-9]','Match'))';
                new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
                
                % Filtering Data
                update_status(app, 'Filtering Data');
                filter_new_data(app, chan);
                
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
                
                % Update figures
                update_status(app, 'Updating Figures');
                app.primary_fig = true;
                update_figures(app, chan);
                
                update_status(app, 'Ready');
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.thresh = app.ThresholdEditField.Value;
            
            if ~isempty(app.N_data)
                update_notify(app, 'spike'); % notify that all channels will need to be redone through the pipeline when selected
                disp_chan = app.DropDown.Value;
                chan = char(regexp(disp_chan,'[0-9]','Match'))';
                
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
                
                % Update figures
                update_status(app, 'Updating Figures');
                app.primary_fig = true;
                update_figures(app, chan);
                
                update_status(app, 'Ready');
                drawnow;
            end
        end

        % Value changed function: BinTimeEditField
        function BinTimeEditFieldValueChanged(app, event)
            app.bin_time = app.BinTimeEditField.Value;
            update_notify(app,'spike'); % notify that all channels will need to be redone through the pipeline when selected
            disp_chan = app.DropDown.Value;
            chan = char(regexp(tab_chan,'[0-9]','Match'))';
            
            check_time_bounds(app, chan)
            
            % Bin new data
            update_status(app, 'Binning Data');
            bin_new_data(app, chan);
            
            % Update figures
            update_status(app, 'Updating Figures');
            app.primary_fig = true;
            update_figures(app, chan);
            
            update_status(app, 'Ready');
        end

        % Value changed function: TimeDropDown
        function TimeDropDownValueChanged(app, event)
            t_type = app.TimeDropDown.Value;
            update_notify(app, 'spike'); % notify that all channels will need to be redone through the pipeline when selected
            % Get bin time
            if strcmp(t_type,'Hours')==true
                app.time_type_scale = 3600;
            elseif strcmp(t_type,'Minutes')==true
                app.time_type_scale = 60;
            elseif strcmp(t_type,'Seconds')==true
                app.time_type_scale = 1;
            end
            
            app.t_start = app.t_start_ref/app.time_type_scale;
            app.t_end = app.t_end_ref/app.time_type_scale;
            
            app.SignalTimeStartEditField.Value = app.t_start;
            app.SignalTimeEndEditField.Value = app.t_end;
            
            disp_chan = app.DropDown.Value;
            chan = char(regexp(disp_chan,'[0-9]','Match'))';
            % Check data exists before updating figures
            if ~isempty(app.N_data)
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
                
                % Update figures
                update_status(app, 'Updating Figures');
                app.primary_fig = true;
                update_figures(app, chan);
                
                update_status(app, 'Ready');
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % save data
            chan_tabs = app.DropDown.Items;
            theme = app.theme;
            % Open the options dialog and pass inputs
            app.SaveMenu.Enable = false;
            app.DialogApp = SaveDialogApp(app, chan_tabs, app.channel_data, theme);
        end

        % Value changed function: ReadChannelsEditField
        function ReadChannelsEditFieldValueChanged(app, event)
            % Get channels to read
            %temp = [];
            %chans = strsplit(app.ReadChannelsEditField.Value,',');
            %for item=1:length(chans)
            %    temp = [temp, str2num(chans{item})];
            %end
            %app.chan_list = temp;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % Clear all channel data
            
            % Display popup asking user if they're sure.
            %fig = uifigure;
            %selection = uiconfirm(fig,'Are you sure you want to erase all data collected?','Clear Data','Icon','warning');
            answer = questdlg('Are you sure you want to erase all data collected?','Clear Data','Confirm','Cancel','Cancel');
            if strcmpi(answer,'confirm')
                app.channel_data = struct;
                cla(app.raw_fig_Axes);
                cla(app.filt_fig_Axes);
                cla(app.spike_fig_Axes);
                cla(app.bin_fig_Axes);
                app.DropDown.Items = {};
                fprintf('Data cleared\n')
            end
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            
        end

        % Button pushed function: NewPathButton
        function NewPathButtonPushed(app, event)
            % Get new file path
            temp_path  = uigetdir(pwd, 'Select new path to folder directory');
            figure(app.PittSPARCSignalInspectorUIFigure)
            if ~temp_path==0
                app.FilePathEditField.Value = temp_path;
                app.folder_path = temp_path;
            else
                fprintf('Error with path')
            end
            
            fprintf('New path set\n')
        end

        % Value changed function: FileTypeEditField
        function FileTypeEditFieldValueChanged(app, event)
            % Easy enough to do a function call
            evaluate_file_type(app)
            
        end

        % Value changed function: ViewArtifactsCheckBox
        function ViewArtifactsCheckBoxValueChanged(app, event)
            value = app.ViewArtifactsCheckBox.Value;
            if ~isempty(app.N_data)
                if value==true
                    app.artifacts_visible=true;
                else
                    app.artifacts_visible=false;
                end
                disp_chan = app.DropDown.Value;
                chan = char(regexp(disp_chan,'[0-9]','Match'))';
                update_raw_data_figure(app, chan);
                
                %update_ylim(app);
            end
        end

        % Close request function: PittSPARCSignalInspectorUIFigure
        function PittSPARCSignalInspectorUIFigureCloseRequest(app, event)
            % Be sure to close all panels
            delete(app.DialogApp)
            delete(app)
            
        end

        % Value changed function: ArtifactThresholdEditField
        function ArtifactThresholdEditFieldValueChanged(app, event)
            update_notify(app,'spike'); % notify that all channels will need to be redone through the pipeline when selected
            disp_chan = app.DropDown.Value;
            chan = char(regexp(disp_chan,'[0-9]','Match'))';
            
            % Check data exists before updating figures
            if ~isempty(app.N_data)
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
                
                % Update figures
                update_status(app, 'Updating Figures');
                app.primary_fig = true;
                update_figures(app, chan);
                
                %app.ViewArtifactsCheckBox.Enable = true;
                %app.ArtifactThresholdEditField.Enable = true;
                update_status(app, 'Ready');
            end
        end

        % Value changed function: DownsampleFactorEditField
        function DownsampleFactorEditFieldValueChanged(app, event)
            % Get downsampling factor and new fs
            
            app.downsample_factor = app.DownsampleFactorEditField.Value;
            
            if ~isempty(app.N_data)
                
                update_notify(app,'raw','filt','spike'); % notify that all channels will need to be redone through the pipeline when selected
                disp_chan = app.DropDown.Value;
                chan = char(regexp(disp_chan,'[0-9]','Match'))';
                
                % Cleaning Data
                update_status(app, 'Cleaning Data');
                clean_new_data(app, chan);
                
                % Filtering Data
                update_status(app, 'Filtering Data');
                filter_new_data(app, chan);
                
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
                
                % Update figures
                update_status(app, 'Updating Figures');
                app.primary_fig = true;
                update_figures(app, chan);
                
                update_status(app, 'Ready');
            else
                app.alt_fs = app.fs/app.DownsampleFactorEditField.Value;
                
            end
        end

        % Value changed function: MinimumVoltageEditField
        function MinimumVoltageEditFieldValueChanged(app, event)
            value = app.MinimumVoltageEditField.Value;
            % Update figures
            update_status(app, 'Updating Figures');
            
            update_ylim(app);
            update_status(app, 'Ready');
        end

        % Value changed function: MaximumVoltageEditField
        function MaximumVoltageEditFieldValueChanged(app, event)
            value = app.MaximumVoltageEditField.Value;
            % Update figures
            update_status(app, 'Updating Figures');
            
            update_ylim(app);
            update_status(app, 'Ready');
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            
            if ~isempty(app.N_data)
                
                update_notify(app,'filt','spike'); % notify that all channels will need to be redone through the pipeline when selected
                disp_chan = app.DropDown.Value;
                chan = char(regexp(disp_chan,'[0-9]','Match'))';
                
                % Filtering Data
                update_status(app, 'Filtering Data');
                filter_new_data(app, chan);
                
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
                
                % Update figures
                update_status(app, 'Updating Figures');
                app.primary_fig = true;
                update_figures(app, chan);
                
                update_status(app, 'Ready');
            end
            
        end

        % Size changed function: raw_data_fig_panel
        function raw_data_fig_panelSizeChanged(app, event)
            position = app.raw_data_fig_panel.Position;
            
        end

        % Value changed function: OrderEditField
        function OrderEditFieldValueChanged(app, event)
            value = app.OrderEditField.Value;
            if ~isempty(app.N_data)
                
                update_notify(app,'filt','spike'); % notify that all channels will need to be redone through the pipeline when selected
                disp_chan = app.DropDown.Value;
                chan = char(regexp(tab_chan,'[0-9]','Match'))';
                new_fs = app.channel_data.(['Channel_',num2str(chan)]).fs/app.DownsampleFactorEditField.Value;
                
                % Filtering Data
                update_status(app, 'Filtering Data');
                filter_new_data(app, chan);
                
                % Bin new data
                update_status(app, 'Binning Data');
                bin_new_data(app, chan);
                
                % Update figures
                update_status(app, 'Updating Figures');
                app.primary_fig = true;
                update_figures(app, chan);
                
                update_status(app, 'Ready');
            end
        end

        % Menu selected function: EnableMenu
        function EnableMenuSelected(app, event)
            if app.p_pool == false
                % start new parallel provcessing pool
                update_status(app, 'Initializing parallel processing...');
                app.p_pool = true;
                app.EnableMenu.Checked = 'on';
                app.DisableMenu.Checked = 'off';
                gcp; % automatically starts new pool in order to grab current pool
                update_status(app, 'Ready');
            end
        end

        % Menu selected function: DisableMenu
        function DisableMenuSelected(app, event)
            if app.p_pool == true
                % start new parallel provcessing pool
                update_status(app, 'Disabling parallel processing...');
                app.p_pool = false;
                app.EnableMenu.Checked = 'off';
                app.DisableMenu.Checked = 'on';
                delete(gcp);SPARCMenuSelected(app, event);
                update_status(app, 'Ready');
            end
        end

        % Menu selected function: SPARCMenu
        function SPARCMenuSelected(app, event)
            % SPARC theme!
            app.theme = 'sparc';
            sparc_menu_theme(app)
        end

        % Menu selected function: PittMenu
        function PittMenuSelected(app, event)
            % Pitt theme!
            app.theme = 'pitt';
            pitt_menu_theme(app)
        end

        % Menu selected function: DefaultMenu
        function DefaultMenuSelected(app, event)
            % Default theme
            app.theme = 'default';
            default_menu_theme(app)
        end

        % Key press function: PittSPARCSignalInspectorUIFigure
        function PittSPARCSignalInspectorUIFigureKeyPress(app, event)
            key = event.Key;
            %beep;
        end

        % Key release function: PittSPARCSignalInspectorUIFigure
        function PittSPARCSignalInspectorUIFigureKeyRelease(app, event)
            key = event.Key;
            %beep;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PittSPARCSignalInspectorUIFigure = uifigure;
            app.PittSPARCSignalInspectorUIFigure.Name = 'Pitt SPARC Signal Inspector';
            app.PittSPARCSignalInspectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @PittSPARCSignalInspectorUIFigureCloseRequest, true);
            app.PittSPARCSignalInspectorUIFigure.KeyPressFcn = createCallbackFcn(app, @PittSPARCSignalInspectorUIFigureKeyPress, true);
            app.PittSPARCSignalInspectorUIFigure.KeyReleaseFcn = createCallbackFcn(app, @PittSPARCSignalInspectorUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.PittSPARCSignalInspectorUIFigure);
            app.FileMenu.Text = 'File';
            app.OptionsMenu = uimenu(app.FileMenu);
            app.OptionsMenu.Separator = 'on';
            app.OptionsMenu.Text = 'Options';
            app.ParallelProcessingMenu = uimenu(app.OptionsMenu);
            app.ParallelProcessingMenu.Text = 'Parallel Processing';
            app.EnableMenu = uimenu(app.ParallelProcessingMenu);
            app.EnableMenu.MenuSelectedFcn = createCallbackFcn(app, @EnableMenuSelected, true);
            app.EnableMenu.Text = 'Enable';
            app.DisableMenu = uimenu(app.ParallelProcessingMenu);
            app.DisableMenu.MenuSelectedFcn = createCallbackFcn(app, @DisableMenuSelected, true);
            app.DisableMenu.Checked = 'on';
            app.DisableMenu.Text = 'Disable';
            app.ThemeMenu = uimenu(app.OptionsMenu);
            app.ThemeMenu.Text = 'Theme';
            app.SPARCMenu = uimenu(app.ThemeMenu);
            app.SPARCMenu.MenuSelectedFcn = createCallbackFcn(app, @SPARCMenuSelected, true);
            app.SPARCMenu.Text = 'SPARC';
            app.PittMenu = uimenu(app.ThemeMenu);
            app.PittMenu.MenuSelectedFcn = createCallbackFcn(app, @PittMenuSelected, true);
            app.PittMenu.Text = 'Pitt';
            app.DefaultMenu = uimenu(app.ThemeMenu);
            app.DefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultMenuSelected, true);
            app.DefaultMenu.Text = 'Default';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.PittSPARCSignalInspectorUIFigure);
            app.HelpMenu.Text = 'Help';
            app.InfoMenu = uimenu(app.HelpMenu);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.TabGroup = uitabgroup(app.PittSPARCSignalInspectorUIFigure);
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.FileConfigurationPanel = uipanel(app.ConfigureTab);
            app.FileConfigurationPanel.Title = 'File Configuration';
            app.ReadChannelsEditFieldLabel = uilabel(app.FileConfigurationPanel);
            app.ReadChannelsEditFieldLabel.Text = 'Read Channels';
            app.ReadChannelsEditField = uieditfield(app.FileConfigurationPanel, 'text');
            app.ReadChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @ReadChannelsEditFieldValueChanged, true);
            app.FileTypeEditFieldLabel = uilabel(app.FileConfigurationPanel);
            app.FileTypeEditFieldLabel.Text = 'File Type';
            app.FileTypeEditField = uieditfield(app.FileConfigurationPanel, 'text');
            app.FileTypeEditField.ValueChangedFcn = createCallbackFcn(app, @FileTypeEditFieldValueChanged, true);
            app.FileTypeEditField.Value = 'ns5';
            app.FilePathEditFieldLabel = uilabel(app.FileConfigurationPanel);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.FileConfigurationPanel, 'text');
            app.FilePathEditField.Value = 'C:\Users\YATESLAB\Documents\GitHub\source';
            app.ExcludeFilesEditFieldLabel = uilabel(app.FileConfigurationPanel);
            app.ExcludeFilesEditFieldLabel.Text = 'Exclude Files';
            app.ExcludeFilesEditField = uieditfield(app.FileConfigurationPanel, 'text');
            app.NewPathButton = uibutton(app.FileConfigurationPanel, 'push');
            app.NewPathButton.ButtonPushedFcn = createCallbackFcn(app, @NewPathButtonPushed, true);
            app.NewPathButton.Text = 'New Path';
            app.ArtifactThresholdEditFieldLabel = uilabel(app.FileConfigurationPanel);
            app.ArtifactThresholdEditFieldLabel.Text = 'Artifact Threshold';
            app.ArtifactThresholdEditField = uieditfield(app.FileConfigurationPanel, 'numeric');
            app.ArtifactThresholdEditField.ValueDisplayFormat = '%1.0f uV';
            app.ArtifactThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ArtifactThresholdEditFieldValueChanged, true);
            app.ArtifactThresholdEditField.Value = 1000;
            app.ViewArtifactsCheckBox = uicheckbox(app.FileConfigurationPanel);
            app.ViewArtifactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewArtifactsCheckBoxValueChanged, true);
            app.ViewArtifactsCheckBox.Text = 'View Artifacts';
            app.FilterSettingsPanel = uipanel(app.ConfigureTab);
            app.FilterSettingsPanel.Title = 'Filter Settings';
            app.HighcutoffHzEditFieldLabel = uilabel(app.FilterSettingsPanel);
            app.HighcutoffHzEditFieldLabel.Text = 'High cutoff              Hz';
            app.HighcutoffHzEditField = uieditfield(app.FilterSettingsPanel, 'numeric');
            app.HighcutoffHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighcutoffHzEditFieldValueChanged, true);
            app.HighcutoffHzEditField.Value = 150;
            app.FilterTypeDropDownLabel = uilabel(app.FilterSettingsPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FilterSettingsPanel);
            app.FilterTypeDropDown.Items = {'Bandpass', 'Highpass', 'Lowpass'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Highpass';
            app.LowcutoffHzEditFieldLabel = uilabel(app.FilterSettingsPanel);
            app.LowcutoffHzEditFieldLabel.Text = 'Low cutoff              Hz';
            app.LowcutoffHzEditField = uieditfield(app.FilterSettingsPanel, 'numeric');
            app.LowcutoffHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowcutoffHzEditFieldValueChanged, true);
            app.LowcutoffHzEditField.Value = 0.001;
            app.OrderEditFieldLabel = uilabel(app.FilterSettingsPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.FilterSettingsPanel, 'numeric');
            app.OrderEditField.Limits = [0 Inf];
            app.OrderEditField.RoundFractionalValues = 'on';
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @OrderEditFieldValueChanged, true);
            app.OrderEditField.Value = 2;
            app.SignalDetectionPanel = uipanel(app.ConfigureTab);
            app.SignalDetectionPanel.Title = 'Signal Detection';
            app.ThresholdEditFieldLabel = uilabel(app.SignalDetectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SignalDetectionPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = -50;
            app.SamplingRateEditFieldLabel = uilabel(app.SignalDetectionPanel);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.SignalDetectionPanel, 'numeric');
            app.SamplingRateEditField.ValueDisplayFormat = '%5.0fkHz';
            app.SamplingRateEditField.Value = 30;
            app.DownsampleFactorEditFieldLabel = uilabel(app.SignalDetectionPanel);
            app.DownsampleFactorEditFieldLabel.Text = 'Downsample Factor';
            app.DownsampleFactorEditField = uieditfield(app.SignalDetectionPanel, 'numeric');
            app.DownsampleFactorEditField.ValueDisplayFormat = '%.0f';
            app.DownsampleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @DownsampleFactorEditFieldValueChanged, true);
            app.DownsampleFactorEditField.Value = 2;
            app.SignalViewingPanel = uipanel(app.ConfigureTab);
            app.SignalViewingPanel.Title = 'Signal Viewing';
            app.BinTimeEditFieldLabel = uilabel(app.SignalViewingPanel);
            app.BinTimeEditFieldLabel.Text = 'Bin Time';
            app.BinTimeEditField = uieditfield(app.SignalViewingPanel, 'numeric');
            app.BinTimeEditField.ValueChangedFcn = createCallbackFcn(app, @BinTimeEditFieldValueChanged, true);
            app.BinTimeEditField.Value = 1;
            app.SignalTimeStartEditFieldLabel = uilabel(app.SignalViewingPanel);
            app.SignalTimeStartEditFieldLabel.Text = 'Signal Time Start';
            app.SignalTimeStartEditField = uieditfield(app.SignalViewingPanel, 'numeric');
            app.SignalTimeStartEditField.ValueChangedFcn = createCallbackFcn(app, @SignalTimeStartEditFieldValueChanged, true);
            app.SignalTimeEndEditFieldLabel = uilabel(app.SignalViewingPanel);
            app.SignalTimeEndEditFieldLabel.Text = 'Signal Time End';
            app.SignalTimeEndEditField = uieditfield(app.SignalViewingPanel, 'numeric');
            app.SignalTimeEndEditField.ValueChangedFcn = createCallbackFcn(app, @SignalTimeEndEditFieldValueChanged, true);
            app.SignalTimeEndEditField.Value = 300;
            app.TimeDropDownLabel = uilabel(app.SignalViewingPanel);
            app.TimeDropDownLabel.Text = 'Time';
            app.TimeDropDown = uidropdown(app.SignalViewingPanel);
            app.TimeDropDown.Items = {'Seconds', 'Minutes', 'Hours'};
            app.TimeDropDown.ValueChangedFcn = createCallbackFcn(app, @TimeDropDownValueChanged, true);
            app.TimeDropDown.Value = 'Seconds';
            app.AmplitudeDropDownLabel = uilabel(app.SignalViewingPanel);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.SignalViewingPanel);
            app.AmplitudeDropDown.Items = {'uV', 'mV'};
            app.AmplitudeDropDown.Value = 'uV';
            app.MinimumVoltageEditFieldLabel = uilabel(app.SignalViewingPanel);
            app.MinimumVoltageEditFieldLabel.Text = 'Minimum Voltage';
            app.MinimumVoltageEditField = uieditfield(app.SignalViewingPanel, 'numeric');
            app.MinimumVoltageEditField.ValueDisplayFormat = '%11.0f uV';
            app.MinimumVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumVoltageEditFieldValueChanged, true);
            app.MaximumVoltageEditFieldLabel = uilabel(app.SignalViewingPanel);
            app.MaximumVoltageEditFieldLabel.Text = 'Maximum Voltage';
            app.MaximumVoltageEditField = uieditfield(app.SignalViewingPanel, 'numeric');
            app.MaximumVoltageEditField.ValueDisplayFormat = '%11.0f uV';
            app.MaximumVoltageEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumVoltageEditFieldValueChanged, true);
            app.ControlPanel = uipanel(app.ConfigureTab);
            app.ControlPanel.Title = 'Control Panel';
            app.LoadNewDataButton = uibutton(app.ControlPanel, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.ClearDataButton = uibutton(app.ControlPanel, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.EditFieldLabel = uilabel(app.ControlPanel);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.ControlPanel, 'text');
            app.EmptyPanel = uipanel(app.ConfigureTab);
            app.DisplayTab = uitab(app.TabGroup);
            app.DisplayTab.Title = 'Display';
            app.DropDownLabel = uilabel(app.DisplayTab);
            app.DropDownLabel.Text = '';
            app.DropDown = uidropdown(app.DisplayTab);
            app.DropDown.Items = {};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = {};
            app.raw_data_fig_panel = uipanel(app.DisplayTab);
            app.raw_data_fig_panel.SizeChangedFcn = createCallbackFcn(app, @raw_data_fig_panelSizeChanged, true);
            app.filt_data_fig_panel = uipanel(app.DisplayTab);
            app.spike_data_fig_panel = uipanel(app.DisplayTab);
            app.bin_data_fig_panel = uipanel(app.DisplayTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pitt_SPARC_Signal_Inspector

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PittSPARCSignalInspectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PittSPARCSignalInspectorUIFigure)
        end
    end
end