classdef SaveDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectchannelstoexportPanel   matlab.ui.container.Panel
        ChannelMenuListBoxLabel       matlab.ui.control.Label
        ChannelMenuListBox            matlab.ui.control.ListBox
        AllCheckBox                   matlab.ui.control.CheckBox
        SelectdatatypestoexportPanel  matlab.ui.container.Panel
        RawDataCheckBox               matlab.ui.control.CheckBox
        FilteredDataCheckBox          matlab.ui.control.CheckBox
        SpikeDataCheckBox             matlab.ui.control.CheckBox
        HistogramDataCheckBox         matlab.ui.control.CheckBox
        SelectsavepathdirectoryandfilenamePanel  matlab.ui.container.Panel
        SavePathEditFieldLabel        matlab.ui.control.Label
        SavePathEditField             matlab.ui.control.EditField
        NewPathButton                 matlab.ui.control.Button
        FileNameEditFieldLabel        matlab.ui.control.Label
        FileNameEditField             matlab.ui.control.EditField
        FileTypeDropDownLabel         matlab.ui.control.Label
        FileTypeDropDown              matlab.ui.control.DropDown
        OKButton                      matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        save_data = struct(); % main struct which will hold saved data
    end
    
    properties (Access = public)
        channel_data = struct();
        save_path = '';
        filter_type = '';
        save_file_type = '';
    end
    
    methods (Access = private)
        
        function save_as_hdf5(app)
            
            fileName = [app.FileNameEditField.Value, '.h5'];
            
            % Create a new file using the default properties.
            file_id = H5F.create (fileName, 'H5F_ACC_TRUNC', 'H5P_DEFAULT', 'H5P_DEFAULT');
            
            gcpl = H5P.create ('H5P_LINK_CREATE');
            H5P.set_create_intermediate_group (gcpl, 1);
            
            % Get final list of channels to save
            selec_chan_list = app.ChannelMenuListBox.Value;
            save_raw = app.RawDataCheckBox.Value;
            save_filt = app.FilteredDataCheckBox.Value;
            save_spike = app.SpikeDataCheckBox.Value;
            save_hist = app.HistogramDataCheckBox.Value;
            
            % Depending on the software reading files saved from Signal-Inspector, the datasets may require a 32xN size
            % instead of a group for each channel holding 1xN datasets
            save_by_chan = false;
            
            if save_by_chan==true
                % collect all data types to save into .mat file
                for i=1:length(selec_chan_list)
                    chan_name = strrep(selec_chan_list{i},' ','_');
                    
                    if (save_raw == true)||(save_filt == true)||(save_spike == true)||(save_hist == true)
                        group = H5G.create (file_id, ['/Channel_Data/',chan_name], gcpl,'H5P_DEFAULT', 'H5P_DEFAULT');
                    end
                    
                    if save_raw == true
                        
                        % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                        raw_dims = size(app.channel_data.(chan_name).raw_data);
                        raw_dataspace_id = H5S.create_simple (2, fliplr(raw_dims), fliplr(raw_dims));
                        
                        % Create the dataset
                        raw_dset_id = H5D.create (file_id, ['/Channel_Data/',chan_name,'/raw_data'], 'H5T_IEEE_F64LE', raw_dataspace_id, 'H5P_DEFAULT');
                        
                        % Write data to dataset
                        wdata = app.channel_data.(chan_name).raw_data;
                        H5D.write (raw_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', wdata);
                        
                        % Close dataspace and dataset
                        H5S.close (raw_dataspace_id);
                        H5D.close (raw_dset_id);
                    end
                    
                    if save_filt == true
                        
                        % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                        filt_dims = size(app.channel_data.(chan_name).raw_data);
                        filt_dataspace_id = H5S.create_simple (2, fliplr(filt_dims), fliplr(filt_dims));
                        
                        % Create the dataset
                        filt_dset_id = H5D.create (file_id, ['/Channel_Data/',chan_name, '/filt_data'], 'H5T_IEEE_F64LE', filt_dataspace_id, 'H5P_DEFAULT');
                        
                        % Write data to dataset
                        wdata = app.channel_data.(chan_name).filt_data;
                        H5D.write (filt_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', wdata);
                        
                        % Close dataspace and dataset
                        H5S.close (filt_dataspace_id);
                        H5D.close (filt_dset_id);
                    end
                    
                    if save_spike == true
                        
                        % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                        spike_dims = size(app.channel_data.(chan_name).spike_data);
                        spike_dataspace_id = H5S.create_simple (2, fliplr(spike_dims), fliplr(spike_dims));
                        
                        % Create the datasets (spikes and spike times have same number of elements, so they can share dataspace ID)
                        spike_dset_id = H5D.create (file_id, ['/Channel_Data/',chan_name, '/spike_data'], 'H5T_IEEE_F64LE', spike_dataspace_id, 'H5P_DEFAULT');
                        spike_t_dset_id = H5D.create (file_id, ['/Channel_Data/',chan_name, '/spike_t'], 'H5T_IEEE_F64LE', spike_dataspace_id, 'H5P_DEFAULT');
                        
                        % Write data to datasets
                        wdata = app.channel_data.(chan_name).spike_data;
                        H5D.write (spike_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', wdata);
                        wdata = app.channel_data.(chan_name).spike_t;
                        H5D.write (spike_t_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', wdata);
                        
                        % Close dataspace and dataset
                        H5S.close (spike_dataspace_id);
                        H5D.close (spike_dset_id);
                        H5D.close (spike_t_dset_id);
                    end
                    
                    if save_hist == true
                        
                        % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                        hist_dims = size(app.channel_data.(chan_name).bin_data);
                        hist_dataspace_id = H5S.create_simple (2, fliplr(hist_dims), fliplr(hist_dims));
                        
                        % Create the datasets (spikes and spike times have same number of elements, so they can share dataspace ID)
                        hist_dset_id = H5D.create (file_id, ['/Channel_Data/',chan_name, '/bin_data'], 'H5T_IEEE_F64LE', hist_dataspace_id, 'H5P_DEFAULT');
                        
                        % Write data to datasets
                        wdata = app.channel_data.(chan_name).bin_data;
                        H5D.write (hist_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', wdata);
                        
                        % Close dataspace and dataset
                        H5S.close (hist_dataspace_id);
                        H5D.close (hist_dset_id);
                    end
                    
                    
                    % --------- Write sample rate to root directory ---------
                    sample_rate = single(app.CallingApp.alt_fs);
                    fs_dims = size(sample_rate);
                    
                    % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                    fs_dataspace_id = H5S.create_simple (2, fliplr(fs_dims), fliplr(fs_dims));
                    
                    % Create the datasets (spikes and spike times have same number of elements, so they can share dataspace ID)
                    fs_dset_id = H5D.create (file_id, ['/Channel_Data/',chan_name,'sampling_rate'], 'H5T_IEEE_F64LE', fs_dataspace_id, 'H5P_DEFAULT');
                    
                    % Write data to datasets
                    
                    H5D.write (fs_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', sample_rate);
                    
                    % Close dataspace and dataset
                    H5S.close (fs_dataspace_id);
                    H5D.close (fs_dset_id);
                    % ---------------------------------------------------------
                    
                end
            else
                if (save_raw == true)||(save_filt == true)||(save_spike == true)||(save_hist == true)
                    group = H5G.create (file_id, '/Channel_Data', gcpl,'H5P_DEFAULT', 'H5P_DEFAULT');
                end
                
                if save_raw == true
                    fprintf('Saving raw data...\n')
                    for i=1:length(selec_chan_list)
                        chan_name = strrep(selec_chan_list{i},' ','_');
                        if ~exist('temp_raw','var')
                            temp_raw = single(zeros(length(selec_chan_list), length(app.channel_data.(chan_name).raw_data)));
                        end
                        temp_raw(i,:) = single(app.channel_data.(chan_name).raw_data);
                    end
                    
                    % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                    raw_dims = size(temp_raw);
                    raw_dataspace_id = H5S.create_simple (2, fliplr(raw_dims), fliplr(raw_dims));
                    
                    % Create the dataset
                    raw_dset_id = H5D.create (file_id, '/Channel_Data/raw_data', 'H5T_IEEE_F64LE', raw_dataspace_id, 'H5P_DEFAULT');
                    
                    % Write data to dataset
                    H5D.write (raw_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', temp_raw);
                    
                    % Close dataspace and dataset
                    H5S.close (raw_dataspace_id);
                    H5D.close (raw_dset_id);
                    
                end
                
                if save_filt == true
                    fprintf('Saving filtered data...\n')
                    for i=1:length(selec_chan_list)
                        chan_name = strrep(selec_chan_list{i},' ','_');
                        if ~exist('temp_filt','var')
                            temp_filt = single(zeros(length(selec_chan_list), length(app.channel_data.(chan_name).filt_data)));
                        end
                        temp_filt(i,:) = app.channel_data.(chan_name).filt_data;
                    end
                    % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                    filt_dims = size(temp_filt);
                    filt_dataspace_id = H5S.create_simple (2, fliplr(filt_dims), fliplr(filt_dims));
                    
                    % Create the dataset
                    filt_dset_id = H5D.create (file_id, '/Channel_Data/filt_data', 'H5T_IEEE_F64LE', filt_dataspace_id, 'H5P_DEFAULT');
                    
                    % Write data to dataset
                    H5D.write (filt_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', temp_filt);
                    
                    % Close dataspace and dataset
                    H5S.close (filt_dataspace_id);
                    H5D.close (filt_dset_id);
                end
                
                if save_spike == true
                    fprintf('Saving spike data...\n')
                    for i=1:length(selec_chan_list)
                        chan_name = strrep(selec_chan_list{i},' ','_');
                        if ~exist('temp_spike','var')
                            temp_spike = single(zeros(length(selec_chan_list), length(app.channel_data.(chan_name).spike_data)));
                            temp_spike_t = single(zeros(length(selec_chan_list), length(app.channel_data.(chan_name).spike_data)));
                        end
                        temp_spike(i,:) = app.channel_data.(chan_name).spike_data;
                        temp_spike_t(i,:) = app.channel_data.(chan_name).spike_t;
                    end
                    % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                    spike_dims = size(temp_spike);
                    spike_dataspace_id = H5S.create_simple (2, fliplr(spike_dims), fliplr(spike_dims));
                    
                    % Create the datasets (spikes and spike times have same number of elements, so they can share dataspace ID)
                    spike_dset_id = H5D.create (file_id, '/Channel_Data/spike_data', 'H5T_IEEE_F64LE', spike_dataspace_id, 'H5P_DEFAULT');
                    spike_t_dset_id = H5D.create (file_id, '/Channel_Data/spike_t', 'H5T_IEEE_F64LE', spike_dataspace_id, 'H5P_DEFAULT');
                    
                    % Write data to datasets
                    H5D.write (spike_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', temp_spike);
                    H5D.write (spike_t_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', temp_spike_t);
                    
                    % Close dataspace and dataset
                    H5S.close (spike_dataspace_id);
                    H5D.close (spike_dset_id);
                    H5D.close (spike_t_dset_id);
                end
                
                if save_hist == true
                    fprintf('Saving histogram data...\n')
                    for i=1:length(selec_chan_list)
                        chan_name = strrep(selec_chan_list{i},' ','_');
                        if ~exist('temp_bin','var')
                            temp_bin = single(zeros(length(selec_chan_list), length(app.channel_data.(chan_name).bin_data)));
                        end
                        temp_bin(i,:) = app.channel_data.(chan_name).bin_data;
                    end
                    
                    % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                    hist_dims = size(temp_bin);
                    hist_dataspace_id = H5S.create_simple (2, fliplr(hist_dims), fliplr(hist_dims));
                    
                    % Create the datasets (spikes and spike times have same number of elements, so they can share dataspace ID)
                    hist_dset_id = H5D.create (file_id, '/Channel_Data/bin_data', 'H5T_IEEE_F64LE', hist_dataspace_id, 'H5P_DEFAULT');
                    
                    % Write data to datasets
                    H5D.write (hist_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', temp_bin);
                    
                    % Close dataspace and dataset
                    H5S.close (hist_dataspace_id);
                    H5D.close (hist_dset_id);
                end
                
                % --------- Write sample rate to root directory ---------
                for i=1:length(selec_chan_list)
                    chan_name = strrep(selec_chan_list{i},' ','_');
                    if ~exist('temp_fs','var')
                        temp_fs = single(zeros(size(selec_chan_list)));
                    end
                    temp_fs(i,1) = app.channel_data.(chan_name).fs;
                end
                    fs_dims = size(temp_fs);
                    
                    % Create dataspace.  Setting maximum size to [] sets the maximum size to be the current size.
                    fs_dataspace_id = H5S.create_simple (2, fliplr(fs_dims), fliplr(fs_dims));
                    
                    % Create the datasets (spikes and spike times have same number of elements, so they can share dataspace ID)
                    fs_dset_id = H5D.create (file_id, '/Channel_Data/sampling_rate', 'H5T_IEEE_F64LE', fs_dataspace_id, 'H5P_DEFAULT');
                    
                    % Write data to datasets
                    
                    H5D.write (fs_dset_id, 'H5T_NATIVE_FLOAT', 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', temp_fs);
                    
                    % Close dataspace and dataset
                    H5S.close (fs_dataspace_id);
                    H5D.close (fs_dset_id);
                
            end
            
            % --------- Write filter settings to root directory ---------
            filter_settings = ['Filter: ', app.filter_type, ' | ', app.CallingApp.freq_range];
            SDIM = size(filter_settings);
            
            % Create file and memory datatypes. MATLAB strings do not have \0's%
            filetype = H5T.copy ('H5T_FORTRAN_S1');
            %H5T.set_size (filetype, SDIM - 1);
            H5T.set_size (filetype, SDIM);
            memtype = H5T.copy ('H5T_C_S1');
            %H5T.set_size (memtype, SDIM - 1);
            H5T.set_size (memtype, SDIM);
            
            settings_dataspace_id = H5S.create_simple (2,fliplr(SDIM), fliplr(SDIM));
            
            % Create the dataset and write the string data to it.
            dset = H5D.create (file_id, 'Filter_settings', filetype, settings_dataspace_id, 'H5P_DEFAULT');
            
            % Transpose the data to match the layout in the H5 file to match C generated H5 file.
            H5D.write (dset, memtype, 'H5S_ALL', 'H5S_ALL', 'H5P_DEFAULT', filter_settings');
            
            % Close and release resources
            H5D.close (dset);
            H5S.close (settings_dataspace_id);
            H5T.close (filetype);
            H5T.close (memtype);
            % ---------------------------------------------------------


            % Close and release resources.
            H5F.close (file_id);
        end
        
        function save_as_mat(app)
            
            % Get final list of channels to save
            selec_chan_list = app.ChannelMenuListBox.Value;
            
            % collect all data types to save into .mat file
            for i=1:length(selec_chan_list)
                chan_name = strrep(selec_chan_list{i},' ','_');
                if app.RawDataCheckBox.Value == true
                    app.save_data.(chan_name).raw_data = app.channel_data.(chan_name).raw_data;
                end
                if app.FilteredDataCheckBox.Value == true
                    app.save_data.(chan_name).filt_data = app.channel_data.(chan_name).filt_data;
                end
                if app.SpikeDataCheckBox.Value == true
                    app.save_data.(chan_name).spike_data = app.channel_data.(chan_name).spike_data;
                    app.save_data.(chan_name).spike_t = app.channel_data.(chan_name).spike_t;
                end
                if app.HistogramDataCheckBox.Value == true
                    app.save_data.(chan_name).bin_data = app.channel_data.(chan_name).bin_data;
                end
                
                app.save_data.(chan_name).sampling_rate = app.channel_data.(chan_name).fs;
            end
            trial_data.channel_data = app.save_data;
            
            trial_data.filter_settings = ['Filter: ', app.filter_type, ' | ', app.CallingApp.freq_range];
            
            % save everything to path
            save_file = [app.FileNameEditField.Value, app.save_file_type];
            save_dir = fullfile(app.save_path, save_file);
            save(save_dir, 'trial_data')
        end
        
        function [status opdataOut]=op_func(rootId,name,opdataIn)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %Operator function for H5O.visit.  This function prints the
            %name and type of the object passed to it.
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fprintf ('/');               % Print root group in object path %
            
            objId=H5O.open(rootId,name,'H5P_DEFAULT');
            type=H5I.get_type(objId);
            H5O.close(objId);
            
            %
            % Check if the current object is the root group, and if not print
            % the full path name and type.
            %
            if (~(name == '.'))         % Root group, do not print '.' %
                
                switch (type)
                    case H5ML.get_constant_value('H5I_GROUP')
                        disp ([name ' (Group)']);
                    case H5ML.get_constant_value('H5I_DATASET')
                        disp ([name ' (Dataset)']);
                    case H5ML.get_constant_value('H5I_DATATYPE')
                        disp ([name ' (Datatype)']);
                    otherwise
                        disp ([name ' (Unknown)']);
                end
            end
            status=0;
            opdataOut=opdataIn;
        end
        
        function sparc_menu_theme(app)
            % SPARC theme!
            app.UIFigure.Color = [0.4, 0.3, 0.7]; %dark purple
            app.SelectdatatypestoexportPanel.BackgroundColor = [0.9,0.9,0.9]; %grey
            app.SelectchannelstoexportPanel.BackgroundColor = [0.9,0.9,0.9];
            app.SelectsavepathdirectoryandfilenamePanel.BackgroundColor = [0.9,0.9,0.9];
            app.OKButton.BackgroundColor = [0.72,0.27,1]; %pink
            app.CancelButton.BackgroundColor = [0.65,0.65,0.65];
        end
        
        function pitt_menu_theme(app)
            % Pitt theme!
            app.UIFigure.Color = [0.25, 0.45, 0.7]; %dark blue
            app.SelectdatatypestoexportPanel.BackgroundColor = [0.9,0.85,0.75]; %sand
            app.SelectchannelstoexportPanel.BackgroundColor = [0.9,0.85,0.75];
            app.SelectsavepathdirectoryandfilenamePanel.BackgroundColor = [0.9,0.85,0.75];
            app.OKButton.BackgroundColor = [1.0,1.0,0]; %yellow
            app.CancelButton.BackgroundColor = [0.65,0.65,0.65];
        end
        
        function default_menu_theme(app)
            % Default matlab theme
            app.UIFigure.Color = [0.94, 0.94, 0.94]; %dark purple
            app.SelectdatatypestoexportPanel.BackgroundColor = [0.9,0.9,0.9]; %grey
            app.SelectchannelstoexportPanel.BackgroundColor = [0.9,0.9,0.9];
            app.SelectsavepathdirectoryandfilenamePanel.BackgroundColor = [0.9,0.9,0.9];
            app.OKButton.BackgroundColor = [0.56,0.8,0.24]; %green
            app.CancelButton.BackgroundColor = [0.65,0.65,0.65];
        end
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, chan_tabs, channel_data, theme)
            % Match theme
            switch theme
                case 'default'
                    %just keep colors
                    default_menu_theme(app);
                case 'sparc'
                    sparc_menu_theme(app);
                case 'pitt'
                    pitt_menu_theme(app);
            end
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            movegui(app.UIFigure,'center');
            
            % Update UI with input values
            app.ChannelMenuListBox.Items = chan_tabs;
            app.filter_type = mainapp.filter_type;
            app.save_file_type = app.FileTypeDropDown.Value;
            app.channel_data = channel_data;
            app.save_path = mainapp.FilePathEditField.Value;
            app.SavePathEditField.Value = mainapp.FilePathEditField.Value;
            
        end

        % Button pushed function: NewPathButton
        function NewPathButtonPushed(app, event)
            temp_path = uigetdir;
            if temp_path~=0
                app.save_path = temp_path;
                app.SavePathEditField.Value = temp_path;
            end
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
            value = app.AllCheckBox.Value;
            if value==1
                ItemsData = app.ChannelMenuListBox.Items; % USE ItemsData instead of Items
                
                % Select all Itmes, by passing the cell array to the Value variable
                app.ChannelMenuListBox.Value = ItemsData; % select all channels in channel menu
            else
                app.ChannelMenuListBox.Value = {};
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            file_type = app.save_file_type;
            
            if strcmp(file_type,'.mat')
                save_as_mat(app);
            else
                % Save as hdf5
                save_as_hdf5(app);
            end
            fprintf('\nSave successful\n')
            
            app.CallingApp.SaveMenu.Enable = true;
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.SaveMenu.Enable = true;
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.SaveMenu.Enable = true;
            % Delete the dialog box
            delete(app)
            
        end

        % Value changed function: FileTypeDropDown
        function FileTypeDropDownValueChanged(app, event)
            app.save_file_type = app.FileTypeDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectchannelstoexportPanel = uipanel(app.UIFigure);
            app.SelectchannelstoexportPanel.Title = 'Select channels to export:';
            app.ChannelMenuListBoxLabel = uilabel(app.SelectchannelstoexportPanel);
            app.ChannelMenuListBoxLabel.Text = 'Channel Menu';
            app.ChannelMenuListBox = uilistbox(app.SelectchannelstoexportPanel);
            app.ChannelMenuListBox.Items = {};
            app.ChannelMenuListBox.Multiselect = 'on';
            app.ChannelMenuListBox.Value = {};
            app.AllCheckBox = uicheckbox(app.SelectchannelstoexportPanel);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.SelectdatatypestoexportPanel = uipanel(app.UIFigure);
            app.SelectdatatypestoexportPanel.Title = 'Select data types to export:';
            app.RawDataCheckBox = uicheckbox(app.SelectdatatypestoexportPanel);
            app.RawDataCheckBox.Text = 'Raw Data';
            app.FilteredDataCheckBox = uicheckbox(app.SelectdatatypestoexportPanel);
            app.FilteredDataCheckBox.Text = 'Filtered Data';
            app.SpikeDataCheckBox = uicheckbox(app.SelectdatatypestoexportPanel);
            app.SpikeDataCheckBox.Text = 'Spike Data';
            app.HistogramDataCheckBox = uicheckbox(app.SelectdatatypestoexportPanel);
            app.HistogramDataCheckBox.Text = 'Histogram Data';
            app.SelectsavepathdirectoryandfilenamePanel = uipanel(app.UIFigure);
            app.SelectsavepathdirectoryandfilenamePanel.Title = 'Select save path directory and file name';
            app.SavePathEditFieldLabel = uilabel(app.SelectsavepathdirectoryandfilenamePanel);
            app.SavePathEditFieldLabel.Visible = 'off';
            app.SavePathEditFieldLabel.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.SelectsavepathdirectoryandfilenamePanel, 'text');
            app.NewPathButton = uibutton(app.SelectsavepathdirectoryandfilenamePanel, 'push');
            app.NewPathButton.ButtonPushedFcn = createCallbackFcn(app, @NewPathButtonPushed, true);
            app.NewPathButton.Text = 'New Path';
            app.FileNameEditFieldLabel = uilabel(app.SelectsavepathdirectoryandfilenamePanel);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.SelectsavepathdirectoryandfilenamePanel, 'text');
            app.FileNameEditField.Value = 'trial_data';
            app.FileTypeDropDownLabel = uilabel(app.SelectsavepathdirectoryandfilenamePanel);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.SelectsavepathdirectoryandfilenamePanel);
            app.FileTypeDropDown.Items = {'.mat', '.hdf5'};
            app.FileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FileTypeDropDownValueChanged, true);
            app.FileTypeDropDown.Value = '.mat';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveDialogApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end